classdef FLOWAPP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        AOASlider                      matlab.ui.control.Slider
        AOASliderLabel                 matlab.ui.control.Label
        TypeDropDown                   matlab.ui.control.DropDown
        TypeDropDownLabel              matlab.ui.control.Label
        ObjectsDropDown                matlab.ui.control.DropDown
        ObjectsDropDownLabel           matlab.ui.control.Label
        DimensionSlider                matlab.ui.control.Slider
        DimensionSliderLabel           matlab.ui.control.Label
        PressureDifferenceSlider       matlab.ui.control.Slider
        PressureDifferenceSliderLabel  matlab.ui.control.Label
        PlotButton                     matlab.ui.control.Button
        UIAxes                         matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)

            switch app.ObjectsDropDown.Value
                case 'Box'
                    % Simulation parameters
                    s = 100;
                    ar = 5;
                    J = [0 1 0; 1 0 1; 0 1 0]/4;
                    n = 120000;
                    
                    % Square vertexes
                    Dim = app.DimensionSlider.Value;
                    a = 50-Dim;
                    b = 50+Dim;
                    c = 50-Dim;
                    d = 55+Dim;
                    
                    % Create a grid
                    [X, Y] = meshgrid(1:s*ar, 1:s);

                    % Initial positions of particles
                    [px, py] = meshgrid(10:10, 1:s);
                    
                    
                    % Save these initial positions for the inflow
                    pxo = px;
                    pyo = py;
                                        
                    % Initialize pressure and velocity fields
                    p = zeros(s, s*ar);
                    vx = zeros(s, s*ar);
                    vy = zeros(s, s*ar);
                    
                    % Main simulation loop (stops when closing figure window)
                    while true
                        try
                            press = app.PressureDifferenceSlider.Value;
                    
                            % Set initial velocity in a specific region
                            if press == 0
                                vx(2:end-1, 2:3) = 0.001;           % Inflow
                            else
                                vx(2:end-1, 2:3) = 1;
                            end
                            vx(a:b, c:d) = 0;               % Inside cube
                            vy(a:b, c:d) = 0;               % Inside cube
                            
                        
                            % Compute right-hand side for pressure equation
                            rhs = -divergence(vx, vy);
                        
                            % Jacobi iteration to solve for pressure
                            for i = 1:100
                                % Update interior cells
                                p = (conv2(p, J, 'same') + rhs/2);
                                p(1:1,1:end) = p(2:2,1:end);                % Top Bound
                                p(end:end,1:end) = p(end-1:end-1,1:end);    % Bottom Bound
                                p(1:end,end:end) = 0;
                                p(1:end,1:1) = press;
                            end
                        
                            % Compute velocity gradient and update velocities for non-boundary pixels
                            [dx, dy] = gradient(p);
                            vx(2:end-1, 2:end-1) = vx(2:end-1, 2:end-1) - dx(2:end-1, 2:end-1);
                            vy(2:end-1, 2:end-1) = vy(2:end-1, 2:end-1) - dy(2:end-1, 2:end-1);
                        
                            % Advect velocity field using Runge-Kutta 4th order method (-1 = backward)
                            [pvx, pvy] = RK4(X, Y, vx, vy, -1);
                            vx = interp2(vx, pvx, pvy, 'linear', 0);
                            vy = interp2(vy, pvx, pvy, 'linear', 0);
                            vx(a:b, c:d) = 0;               % Inside cube
                            vy(a:b, c:d) = 0;               % Inside cube
                            
                            switch app.TypeDropDown.Value
                                case 'Particles'
                                        % Advect particles using Runge-Kutta 4th order method (1 = forward)
                                        [px, py] = RK4(px, py, vx, vy, 1);
                                        % Add the inflow particles
                                        px = [px; pxo];
                                        py = [py; pyo];
                                        % Remove particles after to many
                                        if length(px) > n
                                            px = px(end-n+1:end);
                                            py = py(end-n+1:end);
                                        end
                                        % Remove any outflow particles and ones that fall into cube
                                        index = (px < s*ar-2) & ~((px >= c) & (px <= d) & (py >= a) & (py <= b));
                                        px = px(index);
                                        py = py(index);
                                        % Plotting
                                        scatter(app.UIAxes,px, py, 1, 'filled');
                                        rectangle(app.UIAxes,'Position', [c, a, d-c, b-a], 'EdgeColor', 'r', 'LineWidth', 2);
                                        axis(app.UIAxes,[0 s*ar 0 s])
                                        drawnow;
                                case 'Velocity'
                                % Visualization of velocity field
                                    velocity_Mag = sqrt(vx(2:end-1,10:end).^2 + vy(2:end-1,10:end).^2);
                                    velocity_Mag = imresize(velocity_Mag, 10, 'bicubic');
                                    imagesc(app.UIAxes,velocity_Mag)
                                    colormap(app.UIAxes,'jet')
                                    axis(app.UIAxes,[0 s*ar*10 0 s*10])
                                    drawnow;
                                case 'Vortex'
                                % Visualization of curl field
                                    Vor = abs(curl(vx, vy));
                                    Vor = Vor(2:end-1,10:end-10);
                                    Vor = imresize(Vor, 10, 'bicubic');
                                    imagesc(app.UIAxes,Vor)
                                    colormap(app.UIAxes, 'jet')
                                    axis(app.UIAxes,[0 s*ar*10 0 s*10])
                                    drawnow;
                            end
    
                            catch
                                break;
                        end
                    end

                case 'Circle'
                    s = 100;
                    ar = 5;
                    J = [0 1 0; 1 0 1; 0 1 0]/4; 
                    n = 120000;
                    
                    % Create a grid
                    [X, Y] = meshgrid(1:s*ar, 1:s);
                    
                    % Initialize pressure and velocity fields
                    p = zeros(s, s*ar);
                    vx = zeros(s, s*ar);
                    vy = zeros(s, s*ar);

                    % Initial positions of particles
                    [px, py] = meshgrid(10:10, 1:s);
                    
                    
                    % Save these initial positions for the inflow
                    pxo = px;
                    pyo = py; 
                    
                    % Shape
                    radius_slide = app.DimensionSlider.Value;
                    radius = radius_slide;
                    center1 = [50, 50];

                    mask = sqrt((X-center1(2)).^2+(Y-center1(1)).^2) > radius;

        
                    % Main simulation loop (stops when closing figure window)
                    while true
                        try
                            press = app.PressureDifferenceSlider.Value;
                            % Set initial velocity in a specific region
                            if press == 0
                                vx(2:end-1, 2:3) = 0.001;           % Inflow
                            else
                                vx(2:end-1, 2:3) = 1;
                            end
                            vx(~mask) = 0;
                            vy(~mask) = 0;
                        
                            % Compute right-hand side for pressure equation
                            rhs = -divergence(vx, vy);
                        
                            % Jacobi iteration to solve for pressure
                            for i = 1:100
                                % Update interior cells
                                p = (conv2(p, J, 'same') + rhs/2);
                                p(1:1,1:end) = p(2:2,1:end);                % Top Bound
                                p(end:end,1:end) = p(end-1:end-1,1:end);    % Bottom Bound
                                p(1:end,end:end) = 0;
                                p(1:end,1:1) = press;
                            end
                        
                            % Compute velocity gradient and update velocities for non-boundary pixels
                            [dx, dy] = gradient(p);
                            vx(2:end-1, 2:end-1) = vx(2:end-1, 2:end-1) - dx(2:end-1, 2:end-1);
                            vy(2:end-1, 2:end-1) = vy(2:end-1, 2:end-1) - dy(2:end-1, 2:end-1);
                            vx(~mask) = 0;
                            vy(~mask) = 0;
                        
                            % Advect velocity field using Runge-Kutta 4th order method (-1 = backward)
                            [pvx, pvy] = RK4(X, Y, vx, vy, -1);
                            vx = interp2(vx, pvx, pvy, 'linear', 0);
                            vy = interp2(vy, pvx, pvy, 'linear', 0);
                            
                            switch app.TypeDropDown.Value
                                case 'Particles'
                                        % Advect particles using Runge-Kutta 4th order method (1 = forward)
                                        [px, py] = RK4(px, py, vx, vy, 1);
                                        % Add the inflow particles
                                        px = [px; pxo];
                                        py = [py; pyo];
                                        % Remove particles after to many
                                        if length(px) > n
                                            px = px(end-n+1:end);
                                            py = py(end-n+1:end);
                                        end
                                        % Plotting
                                        scatter(app.UIAxes,px, py, 1, 'filled');
                                        axis(app.UIAxes,[0 s*ar 0 s])
                                        drawnow;
                                case 'Velocity'
                                % Visualization of velocity field
                                    velocity_Mag = sqrt(vx(2:end-1,10:end).^2 + vy(2:end-1,10:end).^2);
                                    velocity_Mag = imresize(velocity_Mag, 10, 'bicubic');
                                    imagesc(app.UIAxes,velocity_Mag)
                                    colormap(app.UIAxes,'jet')
                                    axis(app.UIAxes,[0 s*ar*10 0 s*10])
                                    drawnow;
                                case 'Vortex'
                                % Visualization of curl field
                                    Vor = abs(curl(vx, vy));
                                    Vor = Vor(2:end-1,10:end-10);
                                    Vor = imresize(Vor, 10, 'bicubic');
                                    imagesc(app.UIAxes,Vor)
                                    colormap(app.UIAxes, 'jet')
                                    axis(app.UIAxes,[0 s*ar*10 0 s*10])
                                    drawnow;
                            end

                        catch
                            break;
                        end
                    end


                case 'Circles'
                    s = 100;
                    ar = 5;
                    J = [0 1 0; 1 0 1; 0 1 0]/4; 
                    n = 120000;
                    
                    % Create a grid
                    [X, Y] = meshgrid(1:s*ar, 1:s);
                    
                    % Initialize pressure and velocity fields
                    p = zeros(s, s*ar);
                    vx = zeros(s, s*ar);
                    vy = zeros(s, s*ar);

                    % Initial positions of particles
                    [px, py] = meshgrid(10:10, 1:s);
                    
                    
                    % Save these initial positions for the inflow
                    pxo = px;
                    pyo = py; 
                    
                    % Shape
                    radius_slide = app.DimensionSlider.Value;
                    radius = radius_slide;
                    center1 = [50, 50];
                    center2 = [100, 50];
                    center3 = [0, 50];
                    center4 = [25, 100];
                    center5 = [75, 100];
                    center6 = [50, 150];
                    center7 = [100, 150];
                    center8 = [0, 150];
                    mask = sqrt((X-center1(2)).^2+(Y-center1(1)).^2) > radius & ...
                        sqrt((X-center2(2)).^2+(Y-center2(1)).^2) > radius & ...
                        sqrt((X-center3(2)).^2+(Y-center3(1)).^2) > radius & ...
                        sqrt((X-center4(2)).^2+(Y-center4(1)).^2) > radius & ...
                        sqrt((X-center5(2)).^2+(Y-center5(1)).^2) > radius & ...
                        sqrt((X-center6(2)).^2+(Y-center6(1)).^2) > radius & ...
                        sqrt((X-center7(2)).^2+(Y-center7(1)).^2) > radius & ...
                        sqrt((X-center8(2)).^2+(Y-center8(1)).^2) > radius;
        
                    % Main simulation loop (stops when closing figure window)
                    while true
                        try
                            press = app.PressureDifferenceSlider.Value;
                            % Set initial velocity in a specific region
                            if press == 0
                                vx(2:end-1, 2:3) = 0.001;           % Inflow
                            else
                                vx(2:end-1, 2:3) = 1;
                            end
                            vx(~mask) = 0;
                            vy(~mask) = 0;
                        
                            % Compute right-hand side for pressure equation
                            rhs = -divergence(vx, vy);
                        
                            % Jacobi iteration to solve for pressure
                            for i = 1:100
                                % Update interior cells
                                p = (conv2(p, J, 'same') + rhs/2);
                                p(1:1,1:end) = p(2:2,1:end);                % Top Bound
                                p(end:end,1:end) = p(end-1:end-1,1:end);    % Bottom Bound
                                p(1:end,end:end) = 0;
                                p(1:end,1:1) = press;
                            end
                        
                            % Compute velocity gradient and update velocities for non-boundary pixels
                            [dx, dy] = gradient(p);
                            vx(2:end-1, 2:end-1) = vx(2:end-1, 2:end-1) - dx(2:end-1, 2:end-1);
                            vy(2:end-1, 2:end-1) = vy(2:end-1, 2:end-1) - dy(2:end-1, 2:end-1);
                            vx(~mask) = 0;
                            vy(~mask) = 0;
                        
                            % Advect velocity field using Runge-Kutta 4th order method (-1 = backward)
                            [pvx, pvy] = RK4(X, Y, vx, vy, -1);
                            vx = interp2(vx, pvx, pvy, 'linear', 0);
                            vy = interp2(vy, pvx, pvy, 'linear', 0);
                            
                            switch app.TypeDropDown.Value
                                case 'Particles'
                                        % Advect particles using Runge-Kutta 4th order method (1 = forward)
                                        [px, py] = RK4(px, py, vx, vy, 1);
                                        % Add the inflow particles
                                        px = [px; pxo];
                                        py = [py; pyo];
                                        % Remove particles after to many
                                        if length(px) > n
                                            px = px(end-n+1:end);
                                            py = py(end-n+1:end);
                                        end
                                        % Plotting
                                        scatter(app.UIAxes,px, py, 1, 'filled');
                                        axis(app.UIAxes,[0 s*ar 0 s])
                                        drawnow;
                                case 'Velocity'
                                % Visualization of velocity field
                                    velocity_Mag = sqrt(vx(2:end-1,10:end).^2 + vy(2:end-1,10:end).^2);
                                    velocity_Mag = imresize(velocity_Mag, 10, 'bicubic');
                                    imagesc(app.UIAxes,velocity_Mag)
                                    colormap(app.UIAxes,'jet')
                                    axis(app.UIAxes,[0 s*ar*10 0 s*10])
                                    drawnow;
                                case 'Vortex'
                                % Visualization of curl field
                                    Vor = abs(curl(vx, vy));
                                    Vor = Vor(2:end-1,10:end-10);
                                    Vor = imresize(Vor, 10, 'bicubic');
                                    imagesc(app.UIAxes,Vor)
                                    colormap(app.UIAxes, 'jet')
                                    axis(app.UIAxes,[0 s*ar*10 0 s*10])
                                    drawnow;
                            end

                        catch
                            break;
                        end
                    end


                case 'Airfoil'
                    airfoil = 'NACA2412';
                    AOA = -app.AOASlider.Value;
                    s = 100;
                    ar = 5;
                    J = [0 1 0; 1 0 1; 0 1 0]/4; 
                    n = 120000;
                    chord = app.DimensionSlider.Value/5;
                    
                    % Create a grid
                    [X, Y] = meshgrid(1:s*ar, 1:s);
                    
                    % Initialize pressure and velocity fields
                    p = zeros(s, s*ar);
                    vx = zeros(s, s*ar);
                    vy = zeros(s, s*ar);

                    % Initial positions of particles
                    [px, py] = meshgrid(10:10, 1:s);
                    
                    
                    % Save these initial positions for the inflow
                    pxo = px;
                    pyo = py; 
                    
                    % Define the size of the matrix
                    matrix_height = 100*chord;
                    matrix_width = 100*chord;
                    
                    % Generate the airfoil points (example using provided variables)
                    AirfoilPoints = airfoil_generator(airfoil, 1, 60, 'closed');
                    x_vort = AirfoilPoints(1:2:end, 1)'*1;
                    y_vort = AirfoilPoints(1:2:end, 2)'*1;

                    x_vort_rotated = x_vort * cosd(AOA) - y_vort * sind(AOA);
                    y_vort_rotated = x_vort * sind(AOA) + y_vort * cosd(AOA);
                    
                    % Scale the points to fit within the matrix size
                    x_scaled = round(x_vort_rotated * (matrix_width - 1) / max(x_vort))+30;
                    y_scaled = round((y_vort_rotated) * (matrix_height - 1))+50;
                    
                    % Create the binary matrix
                    airfoil_matrix = zeros(s, s*ar);
                    
                    % Convert the scaled points to a polygon and fill it
                    k = convhull(x_scaled, y_scaled);  % Convex hull to form the border of the airfoil
                    for i = 1:s
                        for j = 1:s*ar
                            if inpolygon(j, i, x_scaled(k), y_scaled(k))
                                airfoil_matrix(i, j) = 1;
                            end
                        end
                    end
                    
                    
                    mask = ~logical(flipud(airfoil_matrix));
        
                    % Main simulation loop (stops when closing figure window)
                    while true
                        try
                            press = app.PressureDifferenceSlider.Value;
                            % Set initial velocity in a specific region
                            if press == 0
                                vx(2:end-1, 2:3) = 0.001;           % Inflow
                            else
                                vx(2:end-1, 2:3) = 1;
                            end
                            vx(~mask) = 0;
                            vy(~mask) = 0;
                        
                            % Compute right-hand side for pressure equation
                            rhs = -divergence(vx, vy);
                        
                            % Jacobi iteration to solve for pressure
                            for i = 1:100
                                % Update interior cells
                                p = (conv2(p, J, 'same') + rhs/2);
                                p(1:1,1:end) = p(2:2,1:end);                % Top Bound
                                p(end:end,1:end) = p(end-1:end-1,1:end);    % Bottom Bound
                                p(1:end,end:end) = 0;
                                p(1:end,1:1) = press;
                            end
                        
                            % Compute velocity gradient and update velocities for non-boundary pixels
                            [dx, dy] = gradient(p);
                            vx(2:end-1, 2:end-1) = vx(2:end-1, 2:end-1) - dx(2:end-1, 2:end-1);
                            vy(2:end-1, 2:end-1) = vy(2:end-1, 2:end-1) - dy(2:end-1, 2:end-1);
                            vx(~mask) = 0;
                            vy(~mask) = 0;
                        
                            % Advect velocity field using Runge-Kutta 4th order method (-1 = backward)
                            [pvx, pvy] = RK4(X, Y, vx, vy, -1);
                            vx = interp2(vx, pvx, pvy, 'linear', 0);
                            vy = interp2(vy, pvx, pvy, 'linear', 0);
                            
                            switch app.TypeDropDown.Value
                                case 'Particles'
                                        % Advect particles using Runge-Kutta 4th order method (1 = forward)
                                        [px, py] = RK4(px, py, vx, vy, 1);
                                        % Add the inflow particles
                                        px = [px; pxo];
                                        py = [py; pyo];
                                        % Remove particles after to many
                                        if length(px) > n
                                            px = px(end-n+1:end);
                                            py = py(end-n+1:end);
                                        end
                                        % Plotting
                                        scatter(app.UIAxes,px, py, 1, 'filled');
                                        axis(app.UIAxes,[0 s*ar 0 s])
                                        drawnow;
                                case 'Velocity'
                                % Visualization of velocity field
                                    velocity_Mag = sqrt(vx(2:end-1,10:end).^2 + vy(2:end-1,10:end).^2);
                                    velocity_Mag = imresize(velocity_Mag, 10, 'bicubic');
                                    imagesc(app.UIAxes,velocity_Mag)
                                    colormap(app.UIAxes,'jet')
                                    axis(app.UIAxes,[0 s*ar*10 0 s*10])
                                    drawnow;
                                case 'Vortex'
                                % Visualization of curl field
                                    Vor = abs(curl(vx, vy));
                                    Vor = Vor(2:end-1,10:end-10);
                                    Vor = imresize(Vor, 10, 'bicubic');
                                    imagesc(app.UIAxes,Vor)
                                    colormap(app.UIAxes, 'jet')
                                    axis(app.UIAxes,[0 s*ar*10 0 s*10])
                                    drawnow;
                            end

                        catch
                            break;
                        end
                    end                    
            end


            % Function for Runge-Kutta 4th order method for advection
            function [x_new, y_new] = RK4(px, py, vx, vy, h)
               k1x = interp2(vx, px, py, 'linear', 0);
               k1y = interp2(vy, px, py, 'linear', 0);
               k2x = interp2(vx, px + h/2 * k1x, py + h/2 * k1y, 'linear', 0);
               k2y = interp2(vy, px + h/2 * k1x, py + h/2 * k1y, 'linear', 0);
               k3x = interp2(vx, px + h/2 * k2x, py + h/2 * k2y, 'linear', 0);
               k3y = interp2(vy, px + h/2 * k2x, py + h/2 * k2y, 'linear', 0);
               k4x = interp2(vx, px + h * k3x, py + h * k3y, 'linear', 0);
               k4y = interp2(vy, px + h * k3x, py + h * k3y, 'linear', 0);
               x_new = px + h/6 * (k1x + 2*k2x + 2*k3x + k4x);
               y_new = py + h/6 * (k1y + 2*k2y + 2*k3y + k4y);
            end

            function Coordinates = airfoil_generator( NACA,c,N,TE_type )
                % A function to generate the coordinates of a 4-digit or 5-digit NACA 
                % airfoil.
                
                digits = char(regexp(NACA, '\d+', 'match'));
                % 4-digit NACA airfoil
                m = str2double(digits(1))/100; % maximum camber
                p = str2double(digits(2))/10; % maximun camber along the chord
                t = str2double(digits(3:4))/100; % max thickness

                x = linspace(0,1,N);
                x = (1-cos(pi*x))/2; % Cosine spacing
                
                % Shape of mean camber
                a0 = 0.2969;
                a1 = -0.1260;
                a2 = -0.3516;
                a3 = 0.2843;
                if strcmp(TE_type,'') || strcmp(TE_type,'closed')
                    a4 = -0.1036; % if close
                elseif strcmp(TE_type,'open')
                    a4 = -.1015; % if open
                end
                
                yt = (t/0.2).*(a0.*sqrt(x) + a1.*(x) + a2.*(x).^2 +...
                    a3.*(x).^3 + a4.*(x).^4);
                
                % surface of the camber

                [yc,zeta] = camber('4digit',p,x,N,m);


                x_U = c.*(x - yt.*sin(zeta));
                x_L = c.*(x + yt.*sin(zeta));
                
                y_U = c.*(yc + yt.*cos(zeta));
                y_L = c.*(yc - yt.*cos(zeta));
                
                % output
                dy = diff(yc);
                dx = diff(x);
                
                Upper = [x_U; y_U];
                Lower = [x_L; y_L];
                
                Upper = Upper';
                Lower = Lower';
                
                Coordinates = vertcat(flip(Upper),Lower(2:end,:));
                
                
                end
                
                % Compute the camber for different airfoils
                function [yc,zeta] = camber(naca,p,x,N,varargin)
                I = find(x > p,1);
                yc = zeros(1,N);
                dyc_dx = zeros(1,N);
                if strcmp(naca,'4digit') % 4-digit equation
                    m = varargin{1};
                    
                    if m ~= 0
                        yc(1:I) = (m/p^2)*(2*p.*x(1:I) - x(1:I).^2);
                        yc(I+1:end) = (m/(1-p)^2)*(1 - 2*p + 2*p.*x(I+1:end) - x(I+1:end).^2);
                        
                        dyc_dx(1:I) = (2*m/p^2)*(p-x(1:I));
                        dyc_dx(I+1:end) = (2*m/(1-p)^2)*(p-x(I+1:end));
                    end
                    
                elseif strcmp(naca,'5digit')  % 5-digit equation 
                    q = varargin{1};
                    cl = varargin{2};
                    
                    if q == 0 % Not reflex
                        [r,k1] = constants(p,q,cl);
                        yc(1:I) = (k1/6)*(x(1:I).^3 - 3*r.*x(1:I).^2 + x(1:I).*r^2*(3-r));
                        yc(I+1:end) = (k1*r.^3)./6 .* (1 - x(I+1:end));
                        
                        dyc_dx(1:I) = (k1/6).* (3*x(1:I).^2 - 6*r.*x(1:I) + r^2 *(3-r));
                        dyc_dx(I+1:end) = -k1*r.^3 ./ 6;
                        
                    elseif q == 1 % reflected camber
                        [r,k1,k2] = constants(p,q,cl);
                        yc(1:I) = (k1/6)*((x(1:I)-r ).^3 - x(1:I)*k2*(1-r)^3 - ...
                            x(1:I)*r^3 + r^3);
                        yc(I+1:end) = (k1/6)*(k2*(x(I+1:end) - r).^3 - ...
                            x(I+1:end).*k2*(1-r)^3 - x(I+1:end).*r^3 + r^3);
                        
                        dyc_dx(1:I) = (k1/6).* (3.*(x(1:I)-r).^2 - k2*(1-r)^3 - r^3);
                        dyc_dx(I+1:end) = (k1/6).*(3.*k2.*(x(I+1:end) - r).^2 - k2.*(1 - r)^3 - r^3);
                    else
                        error('not a valid NACA 5 digit airfoil');
                    end
                    
                    
                end
                
                if dyc_dx ~= zeros(1,N)
                    zeta = atan(dyc_dx);
                else
                    zeta = 0;
                end
                end
                
                % get the constants for different airfoils
                function [m,k1,k2] = constants(p,q,cl)
                
                if q == 0 && nargout == 2
                    A = [.05 0.0580 361.400 ;
                         .10 .1260  51.640 ;
                         .15 .2025  15.957 ;
                         .20 .2900  6.643  ;
                         .25 .3910  3.230] ;
                     
                     I = find(p == A(:,1),1);
                
                    if isempty(I)
                        i = round(I/5);
                        A1 = A(i,:);
                
                        m = (p/A1(1))*A1(2);
                        k1 = (p/A1(1))*A1(3);
                    else
                        m = A(I,2);
                        k1 = A(I,3);
                    end
                elseif q == 1 && nargout == 3
                    A = [.10 .1300 51.990 .000764;
                         .15 .2170 15.793 .00677;
                         .20 .3180 6.520  .0303;
                         .25 .4410 3.191  .1355];
                     
                     I = find(p == A(:,1),1);
                
                    if isempty(I)
                        i = round(I/5);
                        A1 = A(i,:);
                
                        m = (p/A1(1))*A1(2);
                        k1 = (p/A1(1))*A1(3);
                    else
                        m = A(I,2);
                        k1 = A(I,3);
                        k2 = A(I,4);
                    end
                end
                
                % scaling 
                if cl ~= .3 && nargout == 2
                    m = m*cl/.3;
                    k1 = k1*cl/.3;
                elseif cl ~= .3 && nargout == 3
                    m = m*cl/.3;
                    k1 = k1*cl/.3;
                    k2 = k2*cl/.3;
                end

            end
        end

        % Value changed function: PressureDifferenceSlider
        function PressureDifferenceSliderValueChanged(app, event)
            value = app.PressureDifferenceSlider.Value;
            
        end

        % Value changed function: DimensionSlider
        function DimensionSliderValueChanged(app, event)
            value = app.DimensionSlider.Value;
            
        end

        % Value changed function: ObjectsDropDown
        function ObjectsDropDownValueChanged(app, event)
            value = app.ObjectsDropDown.Value;
        end

        % Value changed function: TypeDropDown
        function TypeDropDownValueChanged(app, event)
            value = app.TypeDropDown.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Plot')
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.PressureDifferenceSliderLabel = uilabel(app.UIFigure);
            app.PressureDifferenceSliderLabel.Text = 'Pressure Difference';
            app.PressureDifferenceSlider = uislider(app.UIFigure);
            app.PressureDifferenceSlider.Limits = [0 70];
            app.PressureDifferenceSlider.ValueChangedFcn = createCallbackFcn(app, @PressureDifferenceSliderValueChanged, true);
            app.PressureDifferenceSlider.Value = 1;
            app.DimensionSliderLabel = uilabel(app.UIFigure);
            app.DimensionSliderLabel.Text = 'Dimension';
            app.DimensionSlider = uislider(app.UIFigure);
            app.DimensionSlider.Limits = [5 25];
            app.DimensionSlider.ValueChangedFcn = createCallbackFcn(app, @DimensionSliderValueChanged, true);
            app.DimensionSlider.Value = 5;
            app.ObjectsDropDownLabel = uilabel(app.UIFigure);
            app.ObjectsDropDownLabel.Text = 'Objects';
            app.ObjectsDropDown = uidropdown(app.UIFigure);
            app.ObjectsDropDown.Items = {'Box', 'Circles', 'Circle', 'Airfoil'};
            app.ObjectsDropDown.ValueChangedFcn = createCallbackFcn(app, @ObjectsDropDownValueChanged, true);
            app.ObjectsDropDown.Value = 'Box';
            app.TypeDropDownLabel = uilabel(app.UIFigure);
            app.TypeDropDownLabel.Text = 'Type';
            app.TypeDropDown = uidropdown(app.UIFigure);
            app.TypeDropDown.Items = {'Vortex', 'Particles', 'Velocity'};
            app.TypeDropDown.ValueChangedFcn = createCallbackFcn(app, @TypeDropDownValueChanged, true);
            app.TypeDropDown.Value = 'Vortex';
            app.AOASliderLabel = uilabel(app.UIFigure);
            app.AOASliderLabel.Text = 'AOA';
            app.AOASlider = uislider(app.UIFigure);
            app.AOASlider.Limits = [-10 20];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FLOWAPP

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end